classdef Password_checker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PasswordLabel               matlab.ui.control.Label
        PasswordEditField           matlab.ui.control.EditField
        MomentofTruthButton         matlab.ui.control.Button
        PasswordCheckerLabel        matlab.ui.control.Label
        PressThebuttontocheckLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        RevealButton                matlab.ui.control.StateButton
    end

    methods (Access = private)

        % Value changing function: PasswordEditField
        function password_input(app, event)
%             global changingValue
%             
%             changing = event.Value
%             d = length(changing);
%             changingValue = [changingValue, changing(d)]
%             output = '';
%             for i =  1:length(changing)
%                 output = [output,'*'];
%             end
%             app.PasswordEditField.Value = output;
        end

        % Button pushed function: MomentofTruthButton
        function MomentofTruthButtonPushed(app, event)
            global changingValue
            pass = app.PasswordEditField.Value;
            str = sha(pass);
            if strcmp(pass,'')
               app.TextArea.Value = 'Give me something to work on';
            else
               search = web(str(1:5));
               for i = 1:length(search)
                    data = cell2str(search(i))
                    
                    if strcmp(data(3:37),str(6:end))
                        data = data(39:end-3)
                        app.TextArea.Value = ['Your password have been breached ',data,' times. Change it asap']
                        break
                    end
                    
                    if i<length(search)
                        continue
                    end
                    app.TextArea.Value = 'Chill your password is still protected'
               end
               
            end


        end

        % Value changed function: RevealButton
        function RevealButtonValueChanged(app, event)
%             value = app.RevealButton.Value;
%             data = app.PasswordEditField.Value;
%             global changingValue
%             if value==1
%                 h = 'rllo'
%                 changingValue
%                 app.PasswordEditField.Value = changingValue;
%             else
%                 app.PasswordEditField.Value = data;
%             end
%           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PasswordLabel = uilabel(app.UIFigure);
            app.PasswordLabel.Text = 'Password :';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @password_input, true);
            app.MomentofTruthButton = uibutton(app.UIFigure, 'push');
            app.MomentofTruthButton.ButtonPushedFcn = createCallbackFcn(app, @MomentofTruthButtonPushed, true);
            app.MomentofTruthButton.Text = 'Moment of Truth';
            app.PasswordCheckerLabel = uilabel(app.UIFigure);
            app.PasswordCheckerLabel.Text = 'Password Checker';
            app.PressThebuttontocheckLabel = uilabel(app.UIFigure);
            app.PressThebuttontocheckLabel.Text = 'Press The button to check';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.RevealButton = uibutton(app.UIFigure, 'state');
            app.RevealButton.ValueChangedFcn = createCallbackFcn(app, @RevealButtonValueChanged, true);
            app.RevealButton.Text = 'Reveal';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Password_checker

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end